classdef simulatedRadarWaveformGenerator < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        SimulatedRadarWaveformGenerator  matlab.ui.Figure
        GenTab                        matlab.ui.container.TabGroup
        WaveformgenerationTab         matlab.ui.container.Tab
        UseParallelCheckBox           matlab.ui.control.CheckBox
        GenerateWaveformsButton       matlab.ui.control.Button
        UIAxes                        matlab.ui.control.UIAxes
        PopupFigureButton             matlab.ui.control.Button
        WaveMeta                      matlab.ui.control.TextArea
        SaveWaveformsButton           matlab.ui.control.Button
        PhaseCodingDropDown           matlab.ui.control.DropDown
        PhaseCodingDropDownLabel      matlab.ui.control.Label
        ShowallparametersButton       matlab.ui.control.Button
        PulseModulationDropDownLabel  matlab.ui.control.Label
        PulseModulationDropDown       matlab.ui.control.DropDown
        SamplingFrequencyEditFieldLabel  matlab.ui.control.Label
        SamplingFrequencyEditField    matlab.ui.control.NumericEditField
        NumberofWaveformsEditFieldLabel  matlab.ui.control.Label
        NumberofWaveformsEditField    matlab.ui.control.NumericEditField
        WaveformsIndexLengthListBoxLabel  matlab.ui.control.Label
        WaveformsListBox              matlab.ui.control.ListBox
        AddNoiseTab                   matlab.ui.container.Tab
        LoadWaveformsFolderBtn        matlab.ui.control.Button
        UIAxesTab2                    matlab.ui.control.UIAxes
        WaveformsIndexBinLengthLabel  matlab.ui.control.Label
        WaveformsLstBxTab2            matlab.ui.control.ListBox
        WaveformsLbl                  matlab.ui.control.Label
        NoisepowermodeLabel           matlab.ui.control.Label
        NoisePowerModeDrpDn           matlab.ui.control.DropDown
        NoisePowerRangeEdtFldLower    matlab.ui.control.NumericEditField
        toEditFieldLabel              matlab.ui.control.Label
        NoisePowerRangeEdtFldUpper    matlab.ui.control.NumericEditField
        dBmLbl1                       matlab.ui.control.Label
        stepEditFieldLabel            matlab.ui.control.Label
        stepEdtFld                    matlab.ui.control.NumericEditField
        RadarPeakOrNoisePowerEdtFld   matlab.ui.control.NumericEditField
        dBmLbl2                       matlab.ui.control.Label
        ProcessNoiseAdditionBtn       matlab.ui.control.Button
        SaveDataSetBtn                matlab.ui.control.Button
        PowerModeLbl                  matlab.ui.control.Label
        PopupFigureBtnTab2            matlab.ui.control.Button
        ScaleRadarPeakPowerToLbl      matlab.ui.control.Label
        IncludeNoiseOnlySetChckBx     matlab.ui.control.CheckBox
        WaveformdurationLabel         matlab.ui.control.Label
        WaveformdurationDropDown      matlab.ui.control.DropDown
        msLabel                       matlab.ui.control.Label
        msEditField                   matlab.ui.control.NumericEditField
        SavemethodDropDownLabel       matlab.ui.control.Label
        SaveMethodDrpDn               matlab.ui.control.DropDown
        SubsetsizeEditFieldLabel      matlab.ui.control.Label
        SubsetsizeEditField           matlab.ui.control.NumericEditField
        CovertSubsetToArrayChckBx     matlab.ui.control.CheckBox
        UseParallelCheckBoxNoiseAdd   matlab.ui.control.CheckBox
        SignalcenterfrequencyDropDownLabel  matlab.ui.control.Label
        SignalCenterFreqDrpDn         matlab.ui.control.DropDown
        HzLabel                       matlab.ui.control.Label
        Fs2EdtFd                      matlab.ui.control.NumericEditField
        ReadMeTab                     matlab.ui.container.Tab
        ReadMeText                    matlab.ui.control.TextArea
        Image                         matlab.ui.control.Image
    end

    
    properties (Access = private)
        waveformObj
        waveformsOut
        WaveformTable
        allWaveformTable
        allSigs
        appDir
        %configDir
        resDir
        dspDir
        utilDir
        waveformVarPrefix
        VarNamesDialogApp
    end
    
    methods (Access = private)
        
        function loadReadMe(app)
            defaultText = 'No readme file found';
            readMeFileName = 'README.md';
            
            %Readme is in a different location when compiled
            if (ismcc || isdeployed)
                readmeFilePath = app.resDir;
            else
                readmeFilePath = ['..',filesep,'..',filesep];
            end
            
            readmeFileName = fullfile(readmeFilePath,readMeFileName);
            
            %Use default text if readme isn't found
            if exist(readmeFileName,'file') == 0
                app.ReadMeText.Value = defaultText;
            else
                fid=fopen(readmeFileName,'r');
                app.ReadMeText.Value = fscanf(fid,'%c');
                fclose(fid);
            end
            
        end
    end
    
    methods (Access = public)
        
        function setPrefixVars(app, WaveformDataVariable, SignalStatusVariable,WaveformTableVariable,DataSetRelease)
            app.waveformVarPrefix.WaveformDataVariable=WaveformDataVariable;
            app.waveformVarPrefix.SignalStatusVariable=SignalStatusVariable;
            app.waveformVarPrefix.WaveformTableVariable=WaveformTableVariable;
            app.waveformVarPrefix.DataSetRelease=DataSetRelease;
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            %app.currDir=pwd;
            %% Defaults
            app.appDir=['..',filesep,'app'];
            %app.utilDir='..\util';
            app.dspDir=['..',filesep,'dsp'];
            %app.configDir='..\config';
            app.resDir=['..',filesep,'res'];
            app.utilDir=['..',filesep,'util'];
            
            if ~(ismcc || isdeployed)
                appPath = mfilename('fullpath');
                [progDir,~,~] = fileparts(appPath);
                cd(progDir);
                addpath(app.appDir);
                addpath(app.dspDir);
                %addpath(app.configDir);
                addpath(app.resDir);
                addpath(app.utilDir);
            end
            
            logoFile=fullfile(app.resDir,'CTL_logo.png');
            if isfile(logoFile)
                app.Image.ImageSource=logoFile;
            else
                uialert(app.SimulatedRadarWaveformGenerator,'NIST-CTL logo file does not exist, click OK to continue','warning','Icon','warning');
            end
            
            loadReadMe(app);
            cons=getConstants(radarWaveform);
            app.PulseModulationDropDown.Items=cons.pulseModulationTypes;
            app.SamplingFrequencyEditField.Value=10e6;
            app.NumberofWaveformsEditField.Value=10;
            app.UseParallelCheckBox.Value=false;
            app.PhaseCodingDropDown.Items=cons.PCTypes;
            app.PhaseCodingDropDown.Enable='off';
            app.PhaseCodingDropDownLabel.Enable='off';
            app.NoisePowerModeDrpDn.Value='SNR Range';NoisePowerModeDrpDnValueChanged(app);%set SNR range as default
            app.WaveformdurationDropDown.Value='fix to';WaveformdurationDropDownValueChanged(app);% set fix to as default
            app.msEditField.Value=80;
            app.waveformVarPrefix.WaveformDataVariable='waveformSubset_';
            app.waveformVarPrefix.SignalStatusVariable='radarStatusSubset_';
            app.waveformVarPrefix.WaveformTableVariable='waveformTableSubset_';
            app.waveformVarPrefix.DataSetRelease='1.0.0';
        end

        % Button pushed function: GenerateWaveformsButton
        function GenerateWaveformsButtonPushed(app, event)
            app.waveformObj=radarWaveform(app.SamplingFrequencyEditField.Value,app.PulseModulationDropDown.Value,app.NumberofWaveformsEditField.Value);
            app.waveformObj.useParallel=app.UseParallelCheckBox.Value;
            app.waveformObj.PCType=app.PhaseCodingDropDown.Value;
            app.waveformObj=generateRandomPars(app.waveformObj);
            app.waveformObj=initWave(app.waveformObj);
            app.waveformsOut=generateWave(app.waveformObj,app.SimulatedRadarWaveformGenerator);
            waveOutNumSamps=cellfun(@(x) length(x),app.waveformsOut);
            app.WaveformsListBox.Items=cellstr(num2str([(1:length(waveOutNumSamps)).' waveOutNumSamps]));
            % clf(app.UIAxes);
            app.WaveformsListBox.Value=app.WaveformsListBox.Items(1);
            
            app.WaveformTable=getParsTable(app.waveformObj);
            WaveformsListBoxValueChanged(app);
        end

        % Value changed function: WaveformsListBox
        function WaveformsListBoxValueChanged(app, event)
            value = app.WaveformsListBox.Value;
            Index=find(strcmp(value,app.WaveformsListBox.Items));
            t=1e3*(1/app.SamplingFrequencyEditField.Value)*(0:length(app.waveformsOut{Index})-1).';
            plot(app.UIAxes,t,abs(app.waveformsOut{Index}));
            
            thisWaveStruct=table2struct(app.WaveformTable(Index,:));
            thisWaveFields=fieldnames(thisWaveStruct);
            cellInfo=cellfun(@(x) sprintf('%s: %d',x,thisWaveStruct.(x)),thisWaveFields,'un',0);
            cellInfo(5)=cellfun(@(x) sprintf('%s: %s',x,thisWaveStruct.(x)),thisWaveFields(5),'un',0);
            if strcmp(app.waveformObj.pulseModulation,'P0N#2')
                % cellInfo{6}=sprintf('%s: %d','Actual Pulse Width',app.waveformObj.modifiedPulseWidth(Index));
                %cellInfo{7}=sprintf('%s: %s','Phase Code Type',app.waveformObj.PCType);
                cellInfo{8}=sprintf('%s: %s','PhaseCodingType',app.waveformObj.PCType);
            else
                cellInfo(8)=[];
            end
            app.WaveMeta.Value=(cellInfo);
        end

        % Button pushed function: PopupFigureButton
        function PopupFigureButtonPushed(app, event)
            popupFig = figure;
            ax = axes(popupFig);
            copyobj(app.UIAxes.Children,ax);
            ax.YLim=app.UIAxes.YLim;
            ax.XLim=app.UIAxes.XLim;
            
            ax.Title.String=['Waveform Plot: ',app.WaveformsListBox.Value];
            %ax.Title.Interpreter= 'none';
            ax.YLabel.String='Magnitute (V)';
            ax.XLabel.String='Time (ms)';
        end

        % Button pushed function: SaveWaveformsButton
        function SaveWaveformsButtonPushed(app, event)
            if ~isempty(app.waveformObj)
                FilterSpec='*.mat';
                DialogTitle='Save Waveforms';
                DefaultName=[app.waveformObj.pulseModulation,'_',num2str(app.waveformObj.numWaves),'RadarWaveforms',char(datetime(datetime,'Format','yyyy-MM-dd_HH-mm'))];
                [FileName,PathName] = uiputfile(FilterSpec,DialogTitle,DefaultName);
                
                if PathName~=0
                    %appStruct=struct(app);
                    waveforms=app.waveformsOut;
                    PulseModulation=cell(app.waveformObj.numWaves,1);
                    PulseModulation(:)={app.waveformObj.pulseModulation};
                    SUID=cellfun(@(x) char(java.util.UUID.randomUUID.toString),cell(app.waveformObj.numWaves,1),'un',0);
                    %waveformsInfo=struct(app.waveformObj);
                    waveformTable=[PulseModulation getParsTable(app.waveformObj) SUID];
                    waveformTable.Properties.VariableNames{1}='BinNo';
                    waveformTable.Properties.VariableNames{end}='SUID';
                    save(fullfile(PathName,[FileName]),'waveforms','waveformTable');
                end
            else
                message = sprintf('Waveforms do not exist! \n Generate waveforms first.');
                uialert(app.SimulatedRadarWaveformGenerator,message,'Warning',...
                    'Icon','warning');
            end
        end

        % Value changed function: PulseModulationDropDown
        function PulseModulationDropDownValueChanged(app, event)
            value = app.PulseModulationDropDown.Value;
            if strcmp(value,'P0N#2')
                app.PhaseCodingDropDown.Enable='on';
                app.PhaseCodingDropDownLabel.Enable='on';
            else
                app.PhaseCodingDropDown.Enable='off';
                app.PhaseCodingDropDownLabel.Enable='off';
            end
            
        end

        % Button pushed function: ShowallparametersButton
        function ShowallparametersButtonPushed(app, event)
            if ~isempty(app.WaveformTable)
                tempFig=uifigure('Position', [680 558 650 450],'Name','Radar waveform parameters');
                tempTable=uitable(tempFig,'Position',[5 5 630 400]);
                tempTable.Data=table2cell(app.WaveformTable);
                tempTable.ColumnName=app.WaveformTable.Properties.VariableNames;
            else
                message = sprintf('Waveforms do not exist! \n Generate waveforms first.');
                uialert(app.SimulatedRadarWaveformGenerator,message,'Warning',...
                    'Icon','warning');
            end
            
        end

        % Button pushed function: LoadWaveformsFolderBtn
        function LoadWaveformsFolderBtnButtonPushed(app, event)
            %             InterfaceObj=findobj(app.SimulatedRadarWaveformGenerator,'Enable','on');
            %             set(InterfaceObj,'Enable','off');
            %             drawnow();
            
            try
                fileFields=[{'waveformTable'};{'waveforms'}];
                foldBrows=folderBrowserGUIwithFileCheck(pwd,'Select waveform folder','mat');
                foldBrows=setFileFields(foldBrows,fileFields);
                foldBrows=SelectFolder(foldBrows, app.SimulatedRadarWaveformGenerator);
                [foldBrows,fullFileNames] = checkFileFields(foldBrows);
                busyFig = uiprogressdlg(app.SimulatedRadarWaveformGenerator,'Title','Loading waveforms from folder ...','Indeterminate','on');
                VariableNames={'BinNo','PulseWidth', 'PulsesPerSecond', 'PulsesPerBurst', 'ChirpWidth','ChirpDirection','SamplingFrequency','ActualPulseWidth','PhaseCodingType','SUID'};
                app.allWaveformTable=cell2table(cell(0,length(VariableNames)), 'VariableNames',VariableNames);
                app.allSigs=[];
                for I=1:length(fullFileNames)
                    sigs=load(fullFileNames{I});
                    app.allWaveformTable=[app.allWaveformTable;sigs.waveformTable];
                    app.allSigs=[app.allSigs;sigs.waveforms];
                end
                %            allWaveformTable
                %            allSigs
                waveOutNumSamps=cellfun(@(x) length(x),app.allSigs);
                %            app.WaveformsLstBxTab2.Items=cellstr(num2str([(1:length(waveOutNumSamps)).' waveOutNumSamps]));
                app.WaveformsLstBxTab2.Items=string(1:length(waveOutNumSamps)).'+":"+app.allWaveformTable.BinNo+":"+string(waveOutNumSamps);
                % clf(app.UIAxes);
                folderName=fileparts(fullFileNames{I});
                waveformText{1} = ['No. of waveforms=',num2str(length(app.WaveformsLstBxTab2.Items))];
                waveformText{2}=['From folder:',folderName];
                if length(waveformText{2})>75
                    tempTxt=waveformText{2};
                    waveformText{2}=tempTxt(1:75);
                    waveformText{3}=tempTxt(76:end);
                end
                app.WaveformsLbl.Text=waveformText;
                app.WaveformsLstBxTab2.Value=app.WaveformsLstBxTab2.Items(1);
                app.WaveformsLstBxTab2ValueChanged(app);
                app.ProcessNoiseAdditionBtn.Enable=true;
            catch msg

                %set(InterfaceObj,'Enable','on');
               
               % message = sprintf('Waveforms may not exist! \n Choose correct folder.');
                uialert(app.SimulatedRadarWaveformGenerator,msg.message,'Make sure you chose a correct folder!',...
                    'Icon','error');
            end
            %             set(InterfaceObj,'Enable','on');
            %             drawnow();
            if exist('busyFig','var')==1
                close(busyFig);
            end
        end

        % Value changed function: WaveformsLstBxTab2
        function WaveformsLstBxTab2ValueChanged(app, event)
            value = app.WaveformsLstBxTab2.Value;
            Index=find(strcmp(value,app.WaveformsLstBxTab2.Items));
            t=1e3*(1/app.allWaveformTable.SamplingFrequency(Index))*(0:length(app.allSigs{Index})-1).';
            plot(app.UIAxesTab2,t,abs(app.allSigs{Index}));
            drawnow();
        end

        % Value changed function: NoisePowerModeDrpDn
        function NoisePowerModeDrpDnValueChanged(app, event)
            value = app.NoisePowerModeDrpDn.Value;
            if strcmp(value,'Power Level Range')
                app.dBmLbl1.Text='dBm/MHz,';
                app.PowerModeLbl.Text='Average noise power range:';
                app.NoisePowerRangeEdtFldLower.Value=-109;
                app.NoisePowerRangeEdtFldUpper.Value=-99;
                app.ScaleRadarPeakPowerToLbl.Text='Scale radar peak power to:';
                app.RadarPeakOrNoisePowerEdtFld.Value=-89;
            else
                app.PowerModeLbl.Text='SNR range:';
                app.dBmLbl1.Text='dB';
                app.NoisePowerRangeEdtFldLower.Value=10;
                app.NoisePowerRangeEdtFldUpper.Value=20;
                app.ScaleRadarPeakPowerToLbl.Text='Scale average noise power to:';
                app.RadarPeakOrNoisePowerEdtFld.Value=-109;
            end
        end

        % Button pushed function: ProcessNoiseAdditionBtn
        function ProcessNoiseAdditionBtnButtonPushed(app, event)
            %             InterfaceObj=findobj(app.SimulatedRadarWaveformGenerator,'Enable','on');
            %             set(InterfaceObj,'Enable','off');
            %             drawnow();
            %            pause(0.5);
            %             set(app.SimulatedRadarWaveformGenerator, 'pointer', 'watch')
            %             drawnow;
            
            try
                busyFig = uiprogressdlg(app.SimulatedRadarWaveformGenerator,'Title','Processing noise addition, this may take a while!','Indeterminate','on');
                %                 if app.scaleRadarPowerChckBx.Value
                %                     sigScale=sqrt(db2pow(app.RadarPeakOrNoisePowerEdtFld.Value-30));
                %                 else
                %                     sigScale=1;
                %                 end
                
                lowerVal=app.NoisePowerRangeEdtFldLower.Value;
                upperVal=app.NoisePowerRangeEdtFldUpper.Value;
                stepVal=app.stepEdtFld.Value;
                
                %                 if strcmp(app.NoisePowerModeDrpDn.Value,'Power Level Range')
                %                     rangedBm=lowerVal:stepVal:upperVal;
                %                     %wPowdBmMat=repmat(rangedBm,[length(app.allSigs),1]);
                %                 else
                %                     rangedBm=(pow2db(sigScale^2)+30-lowerVal):stepVal:(pow2db(sigScale^2)+30-upperVal);
                %                     %wPowdBmMat=repmat(rangedBm,[length(app.allSigs),1]);
                %                 end
                %                 for I=1:length(app.allSigs)
                %                     wpow{I,1}=rangedBm(randi([1,length(rangedBm)]))+db2pow(3);
                %                 end
                %                 randIndex=randperm(length(app.allSigs)).';
                %                 app.allSigs=app.allSigs(randIndex,:);
                %                 app.allWaveformTable=app.allWaveformTable(randIndex,:);
                %
                %                 %                 for I=1:length(app.allSigs)
                %                 %                     Hd=firKaiserFlit1MPerFsMHz(app.allWaveformTable.SamplingFrequency(I)/1e6);
                %                 %                     peakPer1MHz(I)=max(Hd(app.allSigs{I}));
                %                 %                 end
                %
                %                 app.allSigs=cellfun(@(x) sigScale*x, app.allSigs,'un',0);
                %                 app.allSigs=cellfun(@(x,y) x+wgn(length(x),1,y,'dBm','complex'), app.allSigs,wpow,'un',0);
                
                wgnAdder = interferenceAdder('wgn');
                
                if strcmp(app.NoisePowerModeDrpDn.Value,'Power Level Range')
                    inPar.radarPeakPowerdBmP1MHzFixed=app.RadarPeakOrNoisePowerEdtFld.Value;
                    inPar.noisePowerdBmP1MHzFixed=NaN;
                else
                    inPar.radarPeakPowerdBmP1MHzFixed=NaN;
                    inPar.noisePowerdBmP1MHzFixed=app.RadarPeakOrNoisePowerEdtFld.Value;
                end
                
                %inPar.sigScale=sigScale;
                inPar.powerLevelMode=app.NoisePowerModeDrpDn.Value;
                inPar.rangedBmOrdB=[lowerVal,stepVal,upperVal];
                inPar.includeNoiseOnlySet=app.IncludeNoiseOnlySetChckBx.Value;
                inPar.waveformDurationMode=app.WaveformdurationDropDown.Value;
                inPar.fixedWaveformDuration=app.msEditField.Value;
                inPar.basebandFreqMode=app.SignalCenterFreqDrpDn.Value;
                inPar.basebandFreqValue=app.Fs2EdtFd.Value;
                inPar.useParallel=app.UseParallelCheckBoxNoiseAdd.Value;
                wgnAdder=setInterferencePar(wgnAdder,inPar);
                [app.allSigs,app.allWaveformTable]=addInterference(wgnAdder,app.allSigs,app.allWaveformTable);
                %SUID_NaN=strcmp(app.allWaveformTable.SUID,'NaN');
                %app.allWaveformTable.SUID(SUID_NaN)=cellfun(@(x) char(java.util.UUID.randomUUID.toString),cell(sum(SUID_NaN),1),'un',0);
                
                app.ProcessNoiseAdditionBtn.Enable=false;
                
                waveOutNumSamps=cellfun(@(x) length(x),app.allSigs);
                %            app.WaveformsLstBxTab2.Items=cellstr(num2str([(1:length(waveOutNumSamps)).' waveOutNumSamps]));
                app.WaveformsLstBxTab2.Items=string(1:length(waveOutNumSamps)).'+":"+app.allWaveformTable.BinNo+":"+string(waveOutNumSamps);
                app.WaveformsLbl.Text=['No. of waveforms=',num2str(length(app.WaveformsLstBxTab2.Items))];
                %app.WaveformsLstBxTab2.Items=app.WaveformsLstBxTab2.Items(newIndex);
                app.WaveformsLstBxTab2.Value=app.WaveformsLstBxTab2.Items(1);
                app.WaveformsLstBxTab2ValueChanged(app);
                
            catch msg
                %set(InterfaceObj,'Enable','on');
                uialert(app.SimulatedRadarWaveformGenerator,msg.message,'Error: On noise addition');
            end
            %             set(InterfaceObj,'Enable','on');
            %             set(app.SimulatedRadarWaveformGenerator, 'pointer', 'arrow')
            %             drawnow();
            if exist('busyFig','var')==1
                close(busyFig);
            end
        end

        % Button pushed function: PopupFigureBtnTab2
        function PopupFigureBtnTab2ButtonPushed(app, event)
            popupFig = figure;
            ax = axes(popupFig);
            copyobj(app.UIAxesTab2.Children,ax);
            ax.YLim=app.UIAxesTab2.YLim;
            ax.XLim=app.UIAxesTab2.XLim;
            
            ax.Title.String=['Waveform Plot: ',app.WaveformsLstBxTab2.Value];
            %ax.Title.Interpreter= 'none';
            ax.YLabel.String='Magnitute (V)';
            ax.XLabel.String='Time (ms)';
        end

        % Button pushed function: SaveDataSetBtn
        function SaveDataSetBtnButtonPushed(app, event)
            InterfaceObj=findobj(app.SimulatedRadarWaveformGenerator,'Enable','on');
            set(InterfaceObj,'Enable','off');
            try
                if ~isempty(app.allWaveformTable)&&~isempty(app.allSigs)
                    FilterSpec='*.mat';
                    DialogTitle='Save Waveform Set';
                    DefaultName=['RadarWaveformSet_',char(datetime(datetime,'Format','yyyy-MM-dd_HH-mm'))];
                    [FileName,PathName] = uiputfile(FilterSpec,DialogTitle,DefaultName);
                    
                    if PathName~=0
                        % create folder for csv tables
                        csvTableFolderPath=fullfile(PathName,[FileName(1:end-length('.mat')),'_CSVInfo']);
                        mkdir(csvTableFolderPath);
                        %appStruct=struct(app);
                        app.VarNamesDialogApp = VarNamesDialog(app, app.waveformVarPrefix.WaveformDataVariable,...
                            app.waveformVarPrefix.SignalStatusVariable, app.waveformVarPrefix.WaveformTableVariable,app.waveformVarPrefix.DataSetRelease);
                        waitfor(app.VarNamesDialogApp);
                        dataVarNamePrefix=app.waveformVarPrefix.WaveformDataVariable;
                        outVarNamePrefix=app.waveformVarPrefix.SignalStatusVariable;
                        waveformTableNamePrefix=app.waveformVarPrefix.WaveformTableVariable;
                        DataSetRelease=app.waveformVarPrefix.DataSetRelease;
                        busyFig = uiprogressdlg(app.SimulatedRadarWaveformGenerator,'Title','Saving waveforms..., this may take a while!','Indeterminate','on');
                        
                        radarStatusSet=app.allWaveformTable.radarStatus;
                        if strcmp(app.SaveMethodDrpDn.Value,'one set')
                            fileNamePath=fullfile(PathName,[FileName]);
                            radarWaveformSet=app.allSigs;
                            radarStatusSet=mat2cell(radarStatusSet,ones(length(radarStatusSet),1));
                            radarWaveformSetTable=app.allWaveformTable;
                            FInfo.FUID=char(java.util.UUID.randomUUID.toString);
                            FInfo.DateTime=datestr(now,'yy-mm-dd_HH:MM:SS.FFF');
                            FInfo.Release=DataSetRelease;
                            File = matfile(fileNamePath, 'Writable', true);
                            File.(dataVarNamePrefix)=radarWaveformSet;
                            File.(outVarNamePrefix)=radarStatusSet;
                            File.(waveformTableNamePrefix)=radarWaveformSetTable;
                            File.('FInfo')=FInfo;
                            clear File;
                            writetable(radarWaveformSetTable,fullfile(csvTableFolderPath,[waveformTableNamePrefix,'.csv']));
                            %save(fileNamePath,'radarWaveformSet','radarStatusSet','radarWaveformSetTable','FInfo');
                        else
                            numOfpointsPersubset=app.SubsetsizeEditField.Value;
                            numOfWaveforms=length(app.allSigs);
                            %                        File = matfile(fileNamePath, 'Writable', true);
                            numOfIterUpper=ceil(numOfWaveforms/numOfpointsPersubset);
                            numOfIterLower=floor(numOfWaveforms/numOfpointsPersubset);
                            %check also if all waveforms have the same length, otherwise subset can't be saved as array
                            waveformLength=cellfun(@length,app.allSigs);


                            if app.CovertSubsetToArrayChckBx.Value && all(waveformLength == waveformLength(1))
                                % save subsets as arrays
                                
                                for I=0:(numOfIterUpper-1)
                                    fileNamePath=fullfile(PathName,[FileName(1:end-length('.mat')),'_',num2str(I+1),'.mat']);
                                    File = matfile(fileNamePath, 'Writable', true);
                                    dataVarName=[dataVarNamePrefix,num2str(I+1)];
                                    outVarName=[outVarNamePrefix,num2str(I+1)];
                                    waveformTableName=[waveformTableNamePrefix,num2str(I+1)];
                                    
                                    if (I+1)==numOfIterUpper && numOfIterUpper~=numOfIterLower
                                        subsetIndx=((I*numOfpointsPersubset+1):numOfWaveforms).';
                                    else
                                        subsetIndx=((I*numOfpointsPersubset+1):(I+1)*numOfpointsPersubset).';
                                    end
                                    FInfo.FUID=char(java.util.UUID.randomUUID.toString);
                                    FInfo.DateTime=datestr(now,'yy-mm-dd_HH:MM:SS.FFF');
                                    FInfo.Release=DataSetRelease;
                                    waveformData=cell2mat(app.allSigs(subsetIndx).');
                                    File.(dataVarName)=waveformData;
                                    File.(outVarName)=radarStatusSet(subsetIndx).';
                                    File.(waveformTableName)=app.allWaveformTable(subsetIndx,:);
                                    File.('FInfo')=FInfo;
                                    clear File;
                                    writetable(app.allWaveformTable(subsetIndx,:),fullfile(csvTableFolderPath,[waveformTableName,'.csv']));
                                end
                                
                            else
                                %save subsets as cell arrays
                                radarStatusSet=mat2cell(radarStatusSet,ones(length(radarStatusSet),1));
                                for I=0:(numOfIterUpper-1)
                                    fileNamePath=fullfile(PathName,[FileName(1:end-length('.mat')),'_',num2str(I+1),'.mat']);
                                    File = matfile(fileNamePath, 'Writable', true);
                                    dataVarName=[dataVarNamePrefix,num2str(I+1)];
                                    outVarName=[outVarNamePrefix,num2str(I+1)];
                                    waveformTableName=[waveformTableNamePrefix,num2str(I+1)];
                                    
                                    if (I+1)==numOfIterUpper && numOfIterUpper~=numOfIterLower
                                        subsetIndx=((I*numOfpointsPersubset+1):numOfWaveforms).';
                                    else
                                        subsetIndx=((I*numOfpointsPersubset+1):(I+1)*numOfpointsPersubset).';
                                    end
                                    FInfo.FUID=char(java.util.UUID.randomUUID.toString);
                                    FInfo.DateTime=datestr(now,'yy-mm-dd_HH:MM:SS.FFF');
                                    FInfo.Release=DataSetRelease;
                                    File.(dataVarName)=app.allSigs(subsetIndx);
                                    File.(outVarName)=radarStatusSet(subsetIndx);
                                    File.(waveformTableName)=app.allWaveformTable(subsetIndx,:);
                                    File.('FInfo')=FInfo;
                                    clear File;
                                    writetable(app.allWaveformTable(subsetIndx,:),fullfile(csvTableFolderPath,[waveformTableName,'.csv']));
                                end
                                
                            end
                        end
                        close(busyFig);
                    end
                else
                    message = sprintf('Wavefomrs do not exist! \n Load waveforms first.');
                    uialert(app.SimulatedRadarWaveformGenerator,message,'Warning',...
                        'Icon','warning');
                end
                
            catch msg
                set(InterfaceObj,'Enable','on');
                uialert(app.SimulatedRadarWaveformGenerator,msg.message,'Error: saving dataset');
            end
            set(InterfaceObj,'Enable','on');
        end

        % Value changed function: WaveformdurationDropDown
        function WaveformdurationDropDownValueChanged(app, event)
            value = app.WaveformdurationDropDown.Value;
            switch value
                case 'keep'
                    app.msEditField.Enable=false;
                case 'fix to max'
                    app.msEditField.Enable=false;
                case 'fix to'
                    app.msEditField.Enable=true;
            end
        end

        % Value changed function: SaveMethodDrpDn
        function SaveMethodDrpDnValueChanged(app, event)
            value = app.SaveMethodDrpDn.Value;
            if strcmp(value,'divide to subsets')
                app.SubsetsizeEditField.Enable=true;
                app.SubsetsizeEditFieldLabel.Enable=true;
                app.CovertSubsetToArrayChckBx.Enable=true;
                app.CovertSubsetToArrayChckBx.Value=true;
            else
               app.SubsetsizeEditField.Enable=false;
               app.SubsetsizeEditFieldLabel.Enable=false;
               app.CovertSubsetToArrayChckBx.Enable=false;
               app.CovertSubsetToArrayChckBx.Value=false;
            end
        end

        % Value changed function: SignalCenterFreqDrpDn
        function SignalCenterFreqDrpDnValueChanged(app, event)
            value = app.SignalCenterFreqDrpDn.Value;
           switch value
                case 'keep'
                    app.Fs2EdtFd.Enable=false;
                    app.HzLabel.Enable=false;
                case 'set at'
                    app.Fs2EdtFd.Enable=true;
                    app.HzLabel.Text='Hz';
                    app.HzLabel.Enable=true;
                    app.Fs2EdtFd.Value=0;
               case 'random'
                    app.Fs2EdtFd.Enable=false;
                    %app.HzLabel.Text='%(Fs/2)';
                    app.HzLabel.Enable=false;
                    %app.Fs2EdtFd.Value=75;
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.SimulatedRadarWaveformGenerator = uifigure('Visible', 'off');
            app.SimulatedRadarWaveformGenerator.Name = 'Simulated Radar Waveform Generator';
            app.GenTab = uitabgroup(app.SimulatedRadarWaveformGenerator);
            app.WaveformgenerationTab = uitab(app.GenTab);
            app.WaveformgenerationTab.Title = 'Waveform generation';
            app.UseParallelCheckBox = uicheckbox(app.WaveformgenerationTab);
            app.UseParallelCheckBox.Text = 'Use Parallel';
            app.GenerateWaveformsButton = uibutton(app.WaveformgenerationTab, 'push');
            app.GenerateWaveformsButton.ButtonPushedFcn = createCallbackFcn(app, @GenerateWaveformsButtonPushed, true);
            app.GenerateWaveformsButton.Text = 'Generate Waveforms';
            app.UIAxes = uiaxes(app.WaveformgenerationTab);
            title(app.UIAxes, 'Waveform Plot')
            xlabel(app.UIAxes, 'Time (ms)')
            ylabel(app.UIAxes, 'Magnitute (V)')
            app.PopupFigureButton = uibutton(app.WaveformgenerationTab, 'push');
            app.PopupFigureButton.ButtonPushedFcn = createCallbackFcn(app, @PopupFigureButtonPushed, true);
            app.PopupFigureButton.Text = 'Pop-up Figure';
            app.WaveMeta = uitextarea(app.WaveformgenerationTab);
            app.WaveMeta.Editable = 'off';
            app.WaveMeta.Value = {'Waveform Info'};
            app.SaveWaveformsButton = uibutton(app.WaveformgenerationTab, 'push');
            app.SaveWaveformsButton.ButtonPushedFcn = createCallbackFcn(app, @SaveWaveformsButtonPushed, true);
            app.SaveWaveformsButton.Text = 'Save Waveforms';
            app.PhaseCodingDropDown = uidropdown(app.WaveformgenerationTab);
            app.PhaseCodingDropDownLabel = uilabel(app.WaveformgenerationTab);
            app.PhaseCodingDropDownLabel.Text = 'P0N#2 Phase Coding';
            app.ShowallparametersButton = uibutton(app.WaveformgenerationTab, 'push');
            app.ShowallparametersButton.ButtonPushedFcn = createCallbackFcn(app, @ShowallparametersButtonPushed, true);
            app.ShowallparametersButton.Text = 'Show all parameters';
            app.PulseModulationDropDownLabel = uilabel(app.WaveformgenerationTab);
            app.PulseModulationDropDownLabel.Text = 'Pulse Modulation';
            app.PulseModulationDropDown = uidropdown(app.WaveformgenerationTab);
            app.PulseModulationDropDown.ValueChangedFcn = createCallbackFcn(app, @PulseModulationDropDownValueChanged, true);
            app.SamplingFrequencyEditFieldLabel = uilabel(app.WaveformgenerationTab);
            app.SamplingFrequencyEditFieldLabel.Text = 'Sampling Frequency';
            app.SamplingFrequencyEditField = uieditfield(app.WaveformgenerationTab, 'numeric');
            app.NumberofWaveformsEditFieldLabel = uilabel(app.WaveformgenerationTab);
            app.NumberofWaveformsEditFieldLabel.Text = 'Number of Waveforms';
            app.NumberofWaveformsEditField = uieditfield(app.WaveformgenerationTab, 'numeric');
            app.WaveformsIndexLengthListBoxLabel = uilabel(app.WaveformgenerationTab);
            app.WaveformsIndexLengthListBoxLabel.Text = 'Waveforms (Index, Length)';
            app.WaveformsListBox = uilistbox(app.WaveformgenerationTab);
            app.WaveformsListBox.Items = {};
            app.WaveformsListBox.ValueChangedFcn = createCallbackFcn(app, @WaveformsListBoxValueChanged, true);
            app.WaveformsListBox.Value = {};
            app.AddNoiseTab = uitab(app.GenTab);
            app.AddNoiseTab.Title = 'Add Noise';
            app.LoadWaveformsFolderBtn = uibutton(app.AddNoiseTab, 'push');
            app.LoadWaveformsFolderBtn.ButtonPushedFcn = createCallbackFcn(app, @LoadWaveformsFolderBtnButtonPushed, true);
            app.LoadWaveformsFolderBtn.Text = 'Load Waveforms Folder';
            app.UIAxesTab2 = uiaxes(app.AddNoiseTab);
            title(app.UIAxesTab2, 'Waveform Plot')
            xlabel(app.UIAxesTab2, 'Time (ms)')
            ylabel(app.UIAxesTab2, 'Magnitute (V)')
            app.WaveformsIndexBinLengthLabel = uilabel(app.AddNoiseTab);
            app.WaveformsIndexBinLengthLabel.Text = 'Waveforms (Index, Bin#, Length)';
            app.WaveformsLstBxTab2 = uilistbox(app.AddNoiseTab);
            app.WaveformsLstBxTab2.Items = {};
            app.WaveformsLstBxTab2.ValueChangedFcn = createCallbackFcn(app, @WaveformsLstBxTab2ValueChanged, true);
            app.WaveformsLstBxTab2.Value = {};
            app.WaveformsLbl = uilabel(app.AddNoiseTab);
            app.WaveformsLbl.Text = 'No. of waveforms=';
            app.NoisepowermodeLabel = uilabel(app.AddNoiseTab);
            app.NoisepowermodeLabel.Text = 'Noise power mode:';
            app.NoisePowerModeDrpDn = uidropdown(app.AddNoiseTab);
            app.NoisePowerModeDrpDn.Items = {'Power Level Range', 'SNR Range'};
            app.NoisePowerModeDrpDn.ValueChangedFcn = createCallbackFcn(app, @NoisePowerModeDrpDnValueChanged, true);
            app.NoisePowerModeDrpDn.Value = 'Power Level Range';
            app.NoisePowerRangeEdtFldLower = uieditfield(app.AddNoiseTab, 'numeric');
            app.NoisePowerRangeEdtFldLower.Value = -109;
            app.toEditFieldLabel = uilabel(app.AddNoiseTab);
            app.toEditFieldLabel.Text = 'to';
            app.NoisePowerRangeEdtFldUpper = uieditfield(app.AddNoiseTab, 'numeric');
            app.NoisePowerRangeEdtFldUpper.Value = -99;
            app.dBmLbl1 = uilabel(app.AddNoiseTab);
            app.dBmLbl1.Text = 'dBm/MHz, ';
            app.stepEditFieldLabel = uilabel(app.AddNoiseTab);
            app.stepEditFieldLabel.Text = 'step:';
            app.stepEdtFld = uieditfield(app.AddNoiseTab, 'numeric');
            app.stepEdtFld.Value = 2;
            app.RadarPeakOrNoisePowerEdtFld = uieditfield(app.AddNoiseTab, 'numeric');
            app.RadarPeakOrNoisePowerEdtFld.Value = -89;
            app.dBmLbl2 = uilabel(app.AddNoiseTab);
            app.dBmLbl2.Text = 'dBm/MHz';
            app.ProcessNoiseAdditionBtn = uibutton(app.AddNoiseTab, 'push');
            app.ProcessNoiseAdditionBtn.ButtonPushedFcn = createCallbackFcn(app, @ProcessNoiseAdditionBtnButtonPushed, true);
            app.ProcessNoiseAdditionBtn.Text = 'Process Noise Addition';
            app.SaveDataSetBtn = uibutton(app.AddNoiseTab, 'push');
            app.SaveDataSetBtn.ButtonPushedFcn = createCallbackFcn(app, @SaveDataSetBtnButtonPushed, true);
            app.SaveDataSetBtn.Text = 'Save Data Set';
            app.PowerModeLbl = uilabel(app.AddNoiseTab);
            app.PowerModeLbl.Text = 'Average noise power range:';
            app.PopupFigureBtnTab2 = uibutton(app.AddNoiseTab, 'push');
            app.PopupFigureBtnTab2.ButtonPushedFcn = createCallbackFcn(app, @PopupFigureBtnTab2ButtonPushed, true);
            app.PopupFigureBtnTab2.Text = 'Pop-up Figure';
            app.ScaleRadarPeakPowerToLbl = uilabel(app.AddNoiseTab);
            app.ScaleRadarPeakPowerToLbl.Text = 'Scale radar peak power to:';
            app.IncludeNoiseOnlySetChckBx = uicheckbox(app.AddNoiseTab);
            app.IncludeNoiseOnlySetChckBx.Text = 'Include noise only set';
            app.IncludeNoiseOnlySetChckBx.Value = true;
            app.WaveformdurationLabel = uilabel(app.AddNoiseTab);
            app.WaveformdurationLabel.Text = 'Waveform duration:';
            app.WaveformdurationDropDown = uidropdown(app.AddNoiseTab);
            app.WaveformdurationDropDown.Items = {'keep', 'fix to max', 'fix to'};
            app.WaveformdurationDropDown.ValueChangedFcn = createCallbackFcn(app, @WaveformdurationDropDownValueChanged, true);
            app.WaveformdurationDropDown.Value = 'keep';
            app.msLabel = uilabel(app.AddNoiseTab);
            app.msLabel.Enable = 'off';
            app.msLabel.Text = 'ms';
            app.msEditField = uieditfield(app.AddNoiseTab, 'numeric');
            app.msEditField.Enable = 'off';
            app.msEditField.Value = 80;
            app.SavemethodDropDownLabel = uilabel(app.AddNoiseTab);
            app.SavemethodDropDownLabel.Text = 'Save method:';
            app.SaveMethodDrpDn = uidropdown(app.AddNoiseTab);
            app.SaveMethodDrpDn.Items = {'one set', 'divide to subsets'};
            app.SaveMethodDrpDn.ValueChangedFcn = createCallbackFcn(app, @SaveMethodDrpDnValueChanged, true);
            app.SaveMethodDrpDn.Value = 'one set';
            app.SubsetsizeEditFieldLabel = uilabel(app.AddNoiseTab);
            app.SubsetsizeEditFieldLabel.Enable = 'off';
            app.SubsetsizeEditFieldLabel.Text = 'Subset size';
            app.SubsetsizeEditField = uieditfield(app.AddNoiseTab, 'numeric');
            app.SubsetsizeEditField.Enable = 'off';
            app.SubsetsizeEditField.Value = 200;
            app.CovertSubsetToArrayChckBx = uicheckbox(app.AddNoiseTab);
            app.CovertSubsetToArrayChckBx.Enable = 'off';
            app.CovertSubsetToArrayChckBx.Text = 'Covert subsets to arrays if possible';
            app.UseParallelCheckBoxNoiseAdd = uicheckbox(app.AddNoiseTab);
            app.UseParallelCheckBoxNoiseAdd.Text = 'Use Parallel';
            app.UseParallelCheckBoxNoiseAdd.Value = true;
            app.SignalcenterfrequencyDropDownLabel = uilabel(app.AddNoiseTab);
            app.SignalcenterfrequencyDropDownLabel.Text = 'Signal center frequency';
            app.SignalCenterFreqDrpDn = uidropdown(app.AddNoiseTab);
            app.SignalCenterFreqDrpDn.Items = {'keep', 'set at', 'random'};
            app.SignalCenterFreqDrpDn.ValueChangedFcn = createCallbackFcn(app, @SignalCenterFreqDrpDnValueChanged, true);
            app.SignalCenterFreqDrpDn.Value = 'keep';
            app.HzLabel = uilabel(app.AddNoiseTab);
            app.HzLabel.Enable = 'off';
            app.HzLabel.Text = 'Hz';
            app.Fs2EdtFd = uieditfield(app.AddNoiseTab, 'numeric');
            app.Fs2EdtFd.Enable = 'off';
            app.ReadMeTab = uitab(app.GenTab);
            app.ReadMeTab.Title = 'ReadMe';
            app.ReadMeText = uitextarea(app.ReadMeTab);
            app.Image = uiimage(app.SimulatedRadarWaveformGenerator);
            % Show the figure after all components are created
            app.SimulatedRadarWaveformGenerator.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = simulatedRadarWaveformGenerator

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.SimulatedRadarWaveformGenerator)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.SimulatedRadarWaveformGenerator)
        end
    end
end