classdef VarNamesDialogAPP < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        VariableNames               matlab.ui.Figure
        WaveformdatavariableprefixLabel  matlab.ui.control.Label
        WaveformDataVariable        matlab.ui.control.EditField
        SignalstatusvariablePrefixLabel  matlab.ui.control.Label
        SignalStatusVariable        matlab.ui.control.EditField
        WaveformtablevariableLabel  matlab.ui.control.Label
        WaveformTableVariable       matlab.ui.control.EditField
        OK                          matlab.ui.control.Button
        DatasetreleasenoLabel       matlab.ui.control.Label
        DataSetRelease              matlab.ui.control.EditField
    end

    
    properties (Access = private)
        CallingApp
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, mainapp, WaveformDataVariable, SignalStatusVariable, WaveformTableVariable, DataSetRelease)
            dialogPos=app.VariableNames.Position;
            mainPos=mainapp.MultiRadarWaveformGenerator.Position;
            dialogPos(1)=mainPos(1)+round(mainPos(3)/2)-round(dialogPos(3)/2);
            dialogPos(2)=mainPos(2)+round(mainPos(4))-round(dialogPos(4)/2);
            app.VariableNames.Position=dialogPos;
            
            app.CallingApp = mainapp;
            app.WaveformDataVariable.Value=WaveformDataVariable;
            app.SignalStatusVariable.Value=SignalStatusVariable;
            app.WaveformTableVariable.Value=WaveformTableVariable;
            app.DataSetRelease.Value=DataSetRelease;
        end

        % Button pushed function: OK
        function OKButtonPushed(app, event)
            setPrefixVars(app.CallingApp, app.WaveformDataVariable.Value, app.SignalStatusVariable.Value,...
                app.WaveformTableVariable.Value,app.DataSetRelease.Value);
            
            % Delete the dialog box
            delete(app)
        end

        % Close request function: VariableNames
        function VariableNamesCloseRequest(app, event)
            delete(app)
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.VariableNames = uifigure('Visible', 'off');
            app.VariableNames.Name = 'Variable Names';
            app.VariableNames.CloseRequestFcn = createCallbackFcn(app, @VariableNamesCloseRequest, true);
            app.WaveformdatavariableprefixLabel = uilabel(app.VariableNames);
            app.WaveformdatavariableprefixLabel.Text = 'Waveform data variable prefix:';
            app.WaveformDataVariable = uieditfield(app.VariableNames, 'text');
            app.SignalstatusvariablePrefixLabel = uilabel(app.VariableNames);
            app.SignalstatusvariablePrefixLabel.Text = 'Signal status variable Prefix:';
            app.SignalStatusVariable = uieditfield(app.VariableNames, 'text');
            app.WaveformtablevariableLabel = uilabel(app.VariableNames);
            app.WaveformtablevariableLabel.Text = 'Waveform table variable';
            app.WaveformTableVariable = uieditfield(app.VariableNames, 'text');
            app.OK = uibutton(app.VariableNames, 'push');
            app.OK.ButtonPushedFcn = createCallbackFcn(app, @OKButtonPushed, true);
            app.OK.Text = 'OK';
            app.DatasetreleasenoLabel = uilabel(app.VariableNames);
            app.DatasetreleasenoLabel.Text = 'Dataset release no.';
            app.DataSetRelease = uieditfield(app.VariableNames, 'text');
            % Show the figure after all components are created
            app.VariableNames.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = VarNamesDialogAPP(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.VariableNames)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.VariableNames)
        end
    end
end